classdef main < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        LoadImageButton      matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
        SelectpointsButton   matlab.ui.control.Button
        InfoButton           matlab.ui.control.Button
        SegmentsButtonGroup  matlab.ui.container.ButtonGroup
        Button_1             matlab.ui.control.ToggleButton
        Button_2             matlab.ui.control.ToggleButton
        Button_3             matlab.ui.control.ToggleButton
        Button_4             matlab.ui.control.ToggleButton
        Button_5             matlab.ui.control.ToggleButton
        Button_7             matlab.ui.control.ToggleButton
        Button_8             matlab.ui.control.ToggleButton
        Button_9             matlab.ui.control.ToggleButton
        Button_6             matlab.ui.control.ToggleButton
        Button_10            matlab.ui.control.ToggleButton
        TabGroup             matlab.ui.container.TabGroup
        DataanalysisTab      matlab.ui.container.Tab
        UITable              matlab.ui.control.Table
        HistogramTab         matlab.ui.container.Tab
        UIHistogramAxes      matlab.ui.control.UIAxes
    end


    properties (Access = public)
        production,
        InputImage, % Description,
        Points,
        Segments,
        SegmentsCordinates,
        LastSegment
    end

    methods (Access = private)
    
        function [segments, cordinates] = searchSegments(app, x, y)
            I = app.InputImage;
            sizeX =  size(I, 1);
            sizeY = size(I,2);
            
            l1x = srodek(x(2), x(9));
            ll2x = srodek(l1x, x(9));
            lr2x = srodek(l1x, x(2));
            
            s1x = [x(9), x(10), l1x, l1x];
            s1y = [y(9), y(10), y(10), y(9)];
            
            s2x = [l1x, x(1), x(2), l1x];
            s2y = [y(1), y(1), y(2), y(2)];
            
            s3x = [x(9), ll2x, ll2x, x(7), x(8)];
            s3y = [y(9), y(9), y(7), y(7), y(8)];
            
            s4x = [ll2x, l1x, l1x, ll2x];
            s4y = [y(9), y(9), y(7), y(7)];
            
            s5x = [l1x, lr2x, lr2x, l1x];
            s5y = [y(2), y(2), y(4), y(4)];
            
            s6x = [lr2x, x(2), x(2), x(4), lr2x];
            s6y = [y(2), y(2), y(3), y(4), y(4)];
            
            s7x = [x(7), ll2x, ll2x, x(6)];
            s7y = [y(7), y(7), y(6), y(6)];
            
            s8x = [ll2x, l1x, l1x, ll2x];
            s8y = [y(7), y(7), y(6), y(6)];
            
            s9x = [l1x, lr2x, lr2x, l1x];
            s9y = [y(4), y(4), y(5), y(5)];
            
            s10x = [lr2x, x(4), x(5), lr2x];
            s10y = [y(4), y(4), y(5), y(5)];
            
            cordinates.X = {s1x, s2x, s3x, s4x, s5x, s6x, s7x, s8x, s9x, s10x};
            cordinates.Y = {s1y, s2y, s3y, s4y, s5y, s6y, s7y, s8y, s9y, s10y};
            
            segm1 = poly2mask(s1x, s1y, sizeX, sizeY);
            segm2 = poly2mask(s2x, s2y, sizeX, sizeY);
            segm3 = poly2mask(s3x, s3y, sizeX, sizeY);
            segm4 = poly2mask(s4x, s4y, sizeX, sizeY);
            segm5 = poly2mask(s5x, s5y, sizeX, sizeY);
            segm6 = poly2mask(s6x, s6y, sizeX, sizeY);
            
            segm7 = poly2mask(s7x, s7y, sizeX, sizeY);
            segm8 = poly2mask(s8x, s8y, sizeX, sizeY);
            segm9 = poly2mask(s9x, s9y, sizeX, sizeY);
            segm10 = poly2mask(s10x, s10y, sizeX, sizeY);
            
            segments = {segm1, segm2, segm3, segm4, segm5, segm6, segm7, segm8, segm9, segm10};
        end
        
        function [meann, modee, mediann, stdd, varr, regionSize] = analiseSegment(app, segmentNumber)
            segmentMask = app.Segments{segmentNumber};
            I = app.InputImage;
            dSegmentMask = double(segmentMask);
            segment = I.*dSegmentMask;
            
            segment = nonzeros(segment);
            meann = mean(double(segment(:)));
            modee = mode(double(segment(:)));
            mediann = median(double(segment(:)));
            
            stdd = std(double(segment(:)));
            varr = var(double(segment(:)));
            
            regionSize = sum(double(segmentMask(:)));

            histogram(app.UIHistogramAxes, segment);
            xlabel(app.UIHistogramAxes, 'Temperature (C)');
            ylabel(app.UIHistogramAxes, 'Number of pixels');
        end
        
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, production)
            close all;
            app.production = production;
            set(app.UIAxes,'xtick',[]);
            set(app.UIAxes,'ytick',[]);
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
             warning('off','MATLAB:imagesci:tifftagsread:badTagValueDivisionByZero')
            
             if app.production
                [file,path] = uigetfile('*.jpg', 'Select a FLIR Image File');
                % workaround
                app.UIFigure.Visible = 'off';
                app.UIFigure.Visible = 'on';
                
                fullpath = fullfile(path,file);
             else
                fullpath = 'C:\Users\Dawid\Documents\GitHub\example.jpg';
             end
            
            I = flir_to_temperature_image(fullpath);
            app.InputImage = I;
          
     
            imagesc(app.UIAxes, I);
            xlim(app.UIAxes, [0 size(I,2)]);
            ylim(app.UIAxes, [0 size(I,1)]);
        end

        % Button pushed function: SelectpointsButton
        function SelectpointsButtonPushed(app, event)
            f = figure
            I = app.InputImage;
            imagesc(I);
            
            if app.production
                pointsNumber = 10;
                
                app.Points.X = [];
                app.Points.Y = [];
                
                for i=1:pointsNumber
                    [x, y] = ginput(1);
                    
                    hold on
                    plot(x(1), y(1), 'r*');
                    text(x, y, ['  ', num2str(i)], 'Color','red');
                    drawnow
                    app.Points.X = [app.Points.X x];
                    app.Points.Y = [app.Points.Y y];
                end
            else 
                % testing points start
                app.Points.X = [1.349650092081031e+02 1.547716390423573e+02 1.547716390423573e+02 1.494898710865562e+02 1.525709023941068e+02 97.112338858195220 99.313075506445670 94.471454880294660 93.591160220994480 1.125174953959484e+02];
                app.Points.Y = [83.864485981308460 93.584112149532760 1.362009345794393e+02 1.489112149532711e+02 1.661074766355141e+02 1.683504672897197e+02 1.511542056074767e+02 1.384439252336449e+02 94.331775700934630 83.864485981308460];
                hold on
                plot(app.Points.X, app.Points.Y, 'r*');
                % testing points end
            end
            
            [app.Segments, app.SegmentsCordinates] = searchSegments(app, app.Points.X, app.Points.Y);
            close(f);
            SegmentsButtonGroupSelectionChanged(app, event);
        end

        % Selection changed function: SegmentsButtonGroup
        function SegmentsButtonGroupSelectionChanged(app, event)
            selectedButton = app.SegmentsButtonGroup.SelectedObject;
            segmentNumber = str2num(selectedButton.Text);

            sx = app.SegmentsCordinates.X{segmentNumber};
            sy = app.SegmentsCordinates.Y{segmentNumber};
            sx = [sx sx(1)];
            sy = [sy sy(1)];
            
            if ~isempty(app.LastSegment)
                delete(app.LastSegment);
            end
            
            hold(app.UIAxes, 'on');
            app.LastSegment = plot(app.UIAxes ,sx, sy, 'r');
            
            [meann, modee, mediann, stdd, varr, regionSize] = analiseSegment(app, segmentNumber);
            cnames = [{'Mean'};{'Mode'};{'Median'};{'Std'};{'Var'}; {'Region size'}];
            stats = [meann; modee; mediann; stdd; varr; regionSize];
            stats = round(stats,2);
            tdata = table(cnames,stats);
            
            format shortG
            app.UITable.Data = tdata;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.LoadImageButton = uibutton(app.UIFigure, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Obraz FLIR')
            app.SelectpointsButton = uibutton(app.UIFigure, 'push');
            app.SelectpointsButton.ButtonPushedFcn = createCallbackFcn(app, @SelectpointsButtonPushed, true);
            app.SelectpointsButton.Text = 'Select points';
            app.InfoButton = uibutton(app.UIFigure, 'push');
            app.InfoButton.Text = 'Info';
            app.SegmentsButtonGroup = uibuttongroup(app.UIFigure);
            app.SegmentsButtonGroup.SelectionChangedFcn = createCallbackFcn(app, @SegmentsButtonGroupSelectionChanged, true);
            app.SegmentsButtonGroup.Title = 'Segments';
            app.Button_1 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_1.Text = '1';
            app.Button_1.Value = true;
            app.Button_2 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_2.Text = '2';
            app.Button_3 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_3.Text = '3';
            app.Button_4 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_4.Text = '4';
            app.Button_5 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_5.Text = '5';
            app.Button_7 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_7.Text = '7';
            app.Button_8 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_8.Text = '8';
            app.Button_9 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_9.Text = '9';
            app.Button_6 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_6.Text = '6';
            app.Button_10 = uitogglebutton(app.SegmentsButtonGroup);
            app.Button_10.Text = '10';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.DataanalysisTab = uitab(app.TabGroup);
            app.DataanalysisTab.Title = 'Data analysis';
            app.UITable = uitable(app.DataanalysisTab);
            app.UITable.ColumnName = {'Function'; 'Value'};
            app.UITable.RowName = {};
            app.HistogramTab = uitab(app.TabGroup);
            app.HistogramTab.Title = 'Histogram';
            app.UIHistogramAxes = uiaxes(app.HistogramTab);
            xlabel(app.UIHistogramAxes, 'Temperature (C)')
            ylabel(app.UIHistogramAxes, 'Number of pixels')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = main(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end